classdef FractalGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FractalGeneratorUIFigure    matlab.ui.Figure
        FormatDropDown              matlab.ui.control.DropDown
        FormatDropDownLabel         matlab.ui.control.Label
        FigureLabel                 matlab.ui.control.Label
        SelectPathButton            matlab.ui.control.Button
        Image_6                     matlab.ui.control.Image
        Image_5                     matlab.ui.control.Image
        Image_4                     matlab.ui.control.Image
        Image_3                     matlab.ui.control.Image
        Image_2                     matlab.ui.control.Image
        Image                       matlab.ui.control.Image
        SavingSettingLabel          matlab.ui.control.Label
        FractalSettingsLabel        matlab.ui.control.Label
        SaveButton                  matlab.ui.control.Button
        AmountEditField             matlab.ui.control.NumericEditField
        AmountEditFieldLabel        matlab.ui.control.Label
        PathEditField               matlab.ui.control.EditField
        AmplitudeEditField          matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel     matlab.ui.control.Label
        BorderSizeEditField         matlab.ui.control.NumericEditField
        BorderSizeLabel             matlab.ui.control.Label
        SizepxEditField             matlab.ui.control.NumericEditField
        SizepxLabel                 matlab.ui.control.Label
        ShapesEditField             matlab.ui.control.NumericEditField
        ShapesEditFieldLabel        matlab.ui.control.Label
        RekursionMaxEditField       matlab.ui.control.NumericEditField
        RekursionMaxEditFieldLabel  matlab.ui.control.Label
        RekursionMinEditField       matlab.ui.control.NumericEditField
        RekursionMinEditFieldLabel  matlab.ui.control.Label
        RandomSeedEditField         matlab.ui.control.NumericEditField
        RandomSeedEditFieldLabel    matlab.ui.control.Label
        ShowSampleButton            matlab.ui.control.Button
        UIExampleAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        seed_reset = true;

        % start shapes
        % triangle
        %x_t = [0, -sqrt(3)/2, sqrt(3)/2];
       % y_t = [1, -1/2, -1/2];
        % rect
        %x_r = [-1 -1 1 1] / sqrt(2);
        %y_r = [-1 1 1 -1] / sqrt(2);
        % pentagon
        %x_p = [0, 0.951, 0.588, -0.588, -0.951];
        %y_p = [1, 0.309, -0.809, -0.809, 0.309];
        X = {[-1 -1 1 1] / sqrt(2); [0, 0.951, 0.588, -0.588, -0.951]};
        Y = {[-1 1 1 -1] / sqrt(2); [1, 0.309, -0.809, -0.809, 0.309]};

        IMG_SIZE;
    end
    
    methods (Access = private)
        
        function [x_frac,y_frac] = deflect(~, x,y, ga_range)
            n_points = size(x,2);

            % output arrays
            x_frac = zeros(1, n_points * 2);
            y_frac = zeros(1, n_points * 2);
        
            % get GA in range [-ga_range ga_range]
            GA = (rand() - 0.5) * ga_range * 2;
        
            for i = 1:2*n_points
                if(mod(i,2) == 0)
                    j1 = i/2;
                    j2 = mod(j1, n_points) + 1;
        
                    mx = (x(j1) + x(j2))/2;
                    my = (y(j1) + y(j2))/2;
                    dx = x(j2) - x(j1);
                    dy = y(j2) - y(j1);
        
                    % get counter clockwise vector
                    norm = sqrt(dx^2 + dy^2);
                    vec_x = -dy / norm;
                    vec_y = dx / norm;
                    
                    x_frac(i) = mx + GA * vec_x;
                    y_frac(i) = my + GA * vec_y;
        
                else
                    j = ceil(i/2);
                    x_frac(i) = x(j);
                    y_frac(i) = y(j);
                end
            end
            
        end
        
        function [out_min, out_max] = get_min_max(~, e, in_min, in_max)
            e_min = min(e);
            e_max = max(e);
        
            % update min
            if e_min < in_min
                out_min = e_min;
            else
                out_min = in_min;
            end
        
            %update max
            if e_max > in_max
                out_max = e_max;
            else
                out_max = in_max;
            end
        end
        
        function color = get_rand_color(~)
            r = randi(4) / 4;
            g = randi(4) / 4;
            b = randi(4) / 4;
            
            color=[r g b];
        end
        
        function generate_fractal(app)
            
            hold(app.UIExampleAxes, "off");

            % parameters
            range_rec = [app.RekursionMinEditField.Value app.RekursionMaxEditField.Value ];
            range_ga = app.AmplitudeEditField.Value;
            n_shapes = app.ShapesEditField.Value;
            border_size = app.BorderSizeEditField.Value;

            %fig = figure;

            x_min = inf;
            x_max = -inf;
            y_min = inf;
            y_max = -inf;
        
            for i = 1:n_shapes
            
                % get x, y of random shape 
                shape = randi(2);
                x = cell2mat(app.X(shape));
                y = cell2mat(app.Y(shape));
            
                % assign random size (size is decreasing)
                % max size: 2
                % min size: 1
                shape_size = (n_shapes - i) * 1/3 + 1;
                x = x * shape_size;
                y = y * shape_size;
                
                n_recursion = randi(range_rec);
            
                for j = 1:n_recursion
                    [x,y] = deflect(app, x,y, range_ga);
                end
                
                [x_min, x_max] = get_min_max(app, x, x_min, x_max);
                [y_min, y_max] = get_min_max(app, y, y_min, y_max);
        
                % style polygon
                color = get_rand_color(app);
                pgon = polyshape(x,y, Simplify=false);
                pg = plot(app.UIExampleAxes,pgon);
                hold(app.UIExampleAxes, "on");
                pg.FaceColor = color;
                pg.EdgeColor = color;
                pg.FaceAlpha = 1;
            end
            
            % style axis            
            border_min = min(x_min, y_min);
            border_max = max(x_max, y_max);
            delta_border = border_max - border_min;
            border = delta_border * border_size;
            xlim(app.UIExampleAxes,[(border_min - border) (border_max + border)]);
            ylim(app.UIExampleAxes,[(border_min - border) (border_max + border)]);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                load("Resources/variables.mat");
                app.RandomSeedEditField.Value   = fractal_settings(1);
                app.RekursionMinEditField.Value = fractal_settings(2);
                app.RekursionMaxEditField.Value = fractal_settings(3);
                app.AmplitudeEditField.Value    = fractal_settings(4);
                app.ShapesEditField.Value       = fractal_settings(5);
                app.BorderSizeEditField.Value   = fractal_settings(6);
                app.AmountEditField.Value       = save_settings(1);
                app.SizepxEditField.Value       = save_settings(2);
                app.PathEditField.Value         = save_path(1);
                app.FormatDropDown.Value        = save_path(2);
            catch
                % Load default values
                app.RandomSeedEditField.Value   = 0;
                app.RekursionMinEditField.Value = 3;
                app.RekursionMaxEditField.Value = 6;
                app.AmplitudeEditField.Value    = 0.5;
                app.ShapesEditField.Value       = 4;
                app.BorderSizeEditField.Value   = 0.05;
                app.PathEditField.Value         = "";
                app.AmountEditField.Value       = 20;
                app.SizepxEditField.Value       = 200;
                app.FormatDropDown.Value        = 'bmp';
            end
            
        end

        % Button pushed function: ShowSampleButton
        function ShowSampleButtonPushed(app, event)
            if app.seed_reset
                rng(app.RandomSeedEditField.Value);
                app.seed_reset = false;
            end
            generate_fractal(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            rng(app.RandomSeedEditField.Value);
            for i = 1:app.AmountEditField.Value
                filename = strcat('fractal_', num2str(i, '%03.f'), '.', app.FormatDropDown.Value);
                app.FigureLabel.Text = filename;
                generate_fractal(app);
                filepath = strcat(app.PathEditField.Value, '/',filename);
                try
                    F = getframe(app.UIExampleAxes, [1 1 322 322]);
                    img = frame2im(F);
                    img = imresize(img, [app.SizepxEditField.Value, app.SizepxEditField.Value]);
                    [img, cmap] = rgb2ind(img, 256);                    
                    imwrite(img, cmap, filepath);
                catch
                    try
                        app.FigureLabel.Text = 'Error! Check the file path.';
                    catch
                        return
                    end
                    return;
                end
            end
            try
                app.FigureLabel.Text = 'done!';
            catch 
            end
        end

        % Button pushed function: SelectPathButton
        function SelectPathButtonPushed(app, event)
            dir = uigetdir();
            if dir == 0
              return
            end
            app.PathEditField.Value = dir;
        end

        % Close request function: FractalGeneratorUIFigure
        function FractalGeneratorUIFigureCloseRequest(app, event)
            fractal_settings = [app.RandomSeedEditField.Value app.RekursionMinEditField.Value app.RekursionMaxEditField.Value app.AmplitudeEditField.Value app.ShapesEditField.Value app.BorderSizeEditField.Value]; 
            save_settings = [app.AmountEditField.Value app.SizepxEditField.Value];

            path_save = app.PathEditField.Value;
            if path_save == ""
                path_save = "Please select a path";
            end
            save_path = [string(path_save) string(app.FormatDropDown.Value)];
            save("Resources/variables.mat", 'fractal_settings',"save_settings", "save_path");
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.FractalGeneratorUIFigure = uifigure('Visible', 'off');
            app.FractalGeneratorUIFigure.Name = 'FractalGenerator';
            app.FractalGeneratorUIFigure.CloseRequestFcn = createCallbackFcn(app, @FractalGeneratorUIFigureCloseRequest, true);
            app.UIExampleAxes = uiaxes(app.FractalGeneratorUIFigure);
            app.UIExampleAxes.XTick = [];
            app.UIExampleAxes.YTick = [];
            app.ShowSampleButton = uibutton(app.FractalGeneratorUIFigure, 'push');
            app.ShowSampleButton.ButtonPushedFcn = createCallbackFcn(app, @ShowSampleButtonPushed, true);
            app.ShowSampleButton.Text = 'Show Sample';
            app.RandomSeedEditFieldLabel = uilabel(app.FractalGeneratorUIFigure);
            app.RandomSeedEditFieldLabel.Text = 'Random Seed';
            app.RandomSeedEditField = uieditfield(app.FractalGeneratorUIFigure, 'numeric');
            app.RandomSeedEditField.Limits = [0 Inf];
            app.RandomSeedEditField.RoundFractionalValues = 'on';
            app.RekursionMinEditFieldLabel = uilabel(app.FractalGeneratorUIFigure);
            app.RekursionMinEditFieldLabel.Text = 'Rekursion Min';
            app.RekursionMinEditField = uieditfield(app.FractalGeneratorUIFigure, 'numeric');
            app.RekursionMinEditField.Limits = [1 10];
            app.RekursionMinEditField.RoundFractionalValues = 'on';
            app.RekursionMinEditField.Value = 3;
            app.RekursionMaxEditFieldLabel = uilabel(app.FractalGeneratorUIFigure);
            app.RekursionMaxEditFieldLabel.Text = 'Rekursion Max';
            app.RekursionMaxEditField = uieditfield(app.FractalGeneratorUIFigure, 'numeric');
            app.RekursionMaxEditField.Limits = [2 10];
            app.RekursionMaxEditField.RoundFractionalValues = 'on';
            app.RekursionMaxEditField.Value = 6;
            app.ShapesEditFieldLabel = uilabel(app.FractalGeneratorUIFigure);
            app.ShapesEditFieldLabel.Text = 'Shapes';
            app.ShapesEditField = uieditfield(app.FractalGeneratorUIFigure, 'numeric');
            app.ShapesEditField.Limits = [1 10];
            app.ShapesEditField.RoundFractionalValues = 'on';
            app.ShapesEditField.Value = 4;
            app.SizepxLabel = uilabel(app.FractalGeneratorUIFigure);
            app.SizepxLabel.Text = 'Size (px)';
            app.SizepxEditField = uieditfield(app.FractalGeneratorUIFigure, 'numeric');
            app.SizepxEditField.Limits = [50 1000];
            app.SizepxEditField.RoundFractionalValues = 'on';
            app.SizepxEditField.Value = 200;
            app.BorderSizeLabel = uilabel(app.FractalGeneratorUIFigure);
            app.BorderSizeLabel.Text = 'Border Size';
            app.BorderSizeEditField = uieditfield(app.FractalGeneratorUIFigure, 'numeric');
            app.BorderSizeEditField.Limits = [0 0.5];
            app.BorderSizeEditField.Value = 0.05;
            app.AmplitudeEditFieldLabel = uilabel(app.FractalGeneratorUIFigure);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.FractalGeneratorUIFigure, 'numeric');
            app.AmplitudeEditField.Limits = [0 1];
            app.AmplitudeEditField.Tooltip = {''};
            app.AmplitudeEditField.Value = 0.5;
            app.PathEditField = uieditfield(app.FractalGeneratorUIFigure, 'text');
            app.AmountEditFieldLabel = uilabel(app.FractalGeneratorUIFigure);
            app.AmountEditFieldLabel.Text = 'Amount';
            app.AmountEditField = uieditfield(app.FractalGeneratorUIFigure, 'numeric');
            app.AmountEditField.Limits = [1 5000];
            app.AmountEditField.RoundFractionalValues = 'on';
            app.AmountEditField.Value = 20;
            app.SaveButton = uibutton(app.FractalGeneratorUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.FractalSettingsLabel = uilabel(app.FractalGeneratorUIFigure);
            app.FractalSettingsLabel.Text = 'Fractal Settings';
            app.SavingSettingLabel = uilabel(app.FractalGeneratorUIFigure);
            app.SavingSettingLabel.Text = 'Saving Setting';
            app.Image = uiimage(app.FractalGeneratorUIFigure);
            app.Image.Tooltip = {'The random seed sets the inital parameter for random number generation. '; ''; 'Changing this will generate different fractals.'; 'The same Random seed will generate the same fractals.'; ''; 'default: 0'};
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Resources', 'Infobutton.png');
            app.Image_2 = uiimage(app.FractalGeneratorUIFigure);
            app.Image_2.Tooltip = {'Minumin number of deflections in the fractal generation process. '; ''; 'Lower values will make simpler fractals.'; 'Higher values will make more complex fractals.'; ''; 'default: 3'};
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'Resources', 'Infobutton.png');
            app.Image_3 = uiimage(app.FractalGeneratorUIFigure);
            app.Image_3.Tooltip = {'Maximumg number of deflections in the fractal generation process. '; ''; 'Lower values will make simpler fractals.'; 'Higher values will make more complex fractals.'; ''; 'default: 6'};
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'Resources', 'Infobutton.png');
            app.Image_4 = uiimage(app.FractalGeneratorUIFigure);
            app.Image_4.Tooltip = {'Strength of the deflection in the fractal generation process.'; ''; 'Lower values will make simpler fractals.'; 'Higher values will make more complex fractals.'; ''; 'default: 0.5'};
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'Resources', 'Infobutton.png');
            app.Image_5 = uiimage(app.FractalGeneratorUIFigure);
            app.Image_5.Tooltip = {'The number of polygons which are layed on top of each other. '; ''; 'default: 4'};
            app.Image_5.ImageSource = fullfile(pathToMLAPP, 'Resources', 'Infobutton.png');
            app.Image_6 = uiimage(app.FractalGeneratorUIFigure);
            app.Image_6.Tooltip = {'The relative border size between the fractal and the image edges.'; ''; 'default: 0.05'};
            app.Image_6.ImageSource = fullfile(pathToMLAPP, 'Resources', 'Infobutton.png');
            app.SelectPathButton = uibutton(app.FractalGeneratorUIFigure, 'push');
            app.SelectPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPathButtonPushed, true);
            app.SelectPathButton.Text = 'Select Path';
            app.FigureLabel = uilabel(app.FractalGeneratorUIFigure);
            app.FigureLabel.Text = '';
            app.FormatDropDownLabel = uilabel(app.FractalGeneratorUIFigure);
            app.FormatDropDownLabel.Text = 'Format';
            app.FormatDropDown = uidropdown(app.FractalGeneratorUIFigure);
            app.FormatDropDown.Items = {'bmp', 'png', 'jpg'};
            app.FormatDropDown.Value = 'bmp';
            % Show the figure after all components are created
            app.FractalGeneratorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FractalGenerator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FractalGeneratorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FractalGeneratorUIFigure)
        end
    end
end